classdef WelcomeScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        VariableButtonGroup      matlab.ui.container.ButtonGroup
        SnowdepthButton          matlab.ui.control.RadioButton
        SnowfallfluxButton       matlab.ui.control.RadioButton
        SurfacesnowamountButton  matlab.ui.control.RadioButton
        Panel_3                  matlab.ui.container.Panel
        Image2                   matlab.ui.control.Image
        Panel_2                  matlab.ui.container.Panel
        Label0                   matlab.ui.control.Label
        YearPanel                matlab.ui.container.Panel
        StartingYearLabel        matlab.ui.control.Label
        FinalYearLabel           matlab.ui.control.Label
        Spinner_2                matlab.ui.control.Spinner
        Spinner                  matlab.ui.control.Spinner
        ExperimentButtonGroup    matlab.ui.container.ButtonGroup
        SSP585Button             matlab.ui.control.RadioButton
        SSP460Button             matlab.ui.control.RadioButton
        SSP534OSButton_2         matlab.ui.control.RadioButton
        SSP126Button_2           matlab.ui.control.RadioButton
        SSP119Button             matlab.ui.control.RadioButton
        SSP434Button_2           matlab.ui.control.RadioButton
        SSP245Button             matlab.ui.control.RadioButton
        SSP370Button             matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        pathToMLAPP = fileparts(mfilename('fullpath'));
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Posizino la finestra correttamente al centro dello schermo in
            % maniera indipendente dalla risoluzione di quest'ultimo
            % Ottengo la larghezza e l'altezza dello schermo
            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
        
            % Ottengo la larghezza e l'altezza della finestra dell'app
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
        
            % Calcolo le coordinate x e y per posizionare la finestra al centro
            x = (screenWidth - appWidth) / 2;
            y = (screenHeight - appHeight) / 2;
        
            % Imposto la posizione della finestra
            app.UIFigure.Position(1) = x;
            app.UIFigure.Position(2) = y;
            
            app.UIFigure.Name = 'Ciao';
            borderColor = [0.64, 0.08, 0.18];
            app.Panel_2.BorderColor = borderColor;
            app.Panel_3.BorderColor = borderColor;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.ExperimentButtonGroup = uibuttongroup(app.Panel);
            app.ExperimentButtonGroup.Title = 'Experiment';
            app.SSP370Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP370Button.Text = 'SSP3-7.0';
            app.SSP245Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP245Button.Text = 'SSP2-4.5';
            app.SSP434Button_2 = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP434Button_2.Text = 'SSP4-3.4';
            app.SSP119Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP119Button.Text = 'SSP1-1.9';
            app.SSP119Button.Value = true;
            app.SSP126Button_2 = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP126Button_2.Text = 'SSP1-2.6';
            app.SSP534OSButton_2 = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP534OSButton_2.Text = 'SSP5-3.4OS';
            app.SSP460Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP460Button.Text = 'SSP4-6.0';
            app.SSP585Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP585Button.Text = 'SSP5-8.5';
            app.YearPanel = uipanel(app.Panel);
            app.YearPanel.Title = 'Year';
            app.Spinner = uispinner(app.YearPanel);
            app.Spinner.Limits = [2016 2100];
            app.Spinner.ValueDisplayFormat = '%.0f';
            app.Spinner.Value = 2016;
            app.Spinner_2 = uispinner(app.YearPanel);
            app.Spinner_2.Limits = [2016 2100];
            app.Spinner_2.ValueDisplayFormat = '%.0f';
            app.Spinner_2.Value = 2024;
            app.FinalYearLabel = uilabel(app.YearPanel);
            app.FinalYearLabel.Text = 'Final Year';
            app.StartingYearLabel = uilabel(app.YearPanel);
            app.StartingYearLabel.Text = 'Starting Year';
            app.Panel_2 = uipanel(app.Panel);
            app.Label0 = uilabel(app.Panel_2);
            app.Label0.Text = 'CMIP6 Climate Projections Visualizer';
            app.Panel_3 = uipanel(app.Panel);
            app.Image2 = uiimage(app.Panel_3);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'res', 'top_banner_3.png');
            app.VariableButtonGroup = uibuttongroup(app.Panel);
            app.VariableButtonGroup.Title = 'Variable';
            app.SurfacesnowamountButton = uiradiobutton(app.VariableButtonGroup);
            app.SurfacesnowamountButton.Text = 'Surface snow amount';
            app.SurfacesnowamountButton.Value = true;
            app.SnowfallfluxButton = uiradiobutton(app.VariableButtonGroup);
            app.SnowfallfluxButton.Text = 'Snowfall flux';
            app.SnowdepthButton = uiradiobutton(app.VariableButtonGroup);
            app.SnowdepthButton.Text = 'Snow depth';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WelcomeScreen

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end